classdef BRM_Microscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        AcquisitionStatusPanel         matlab.ui.container.Panel
        SaveDirectoryLampLabel         matlab.ui.control.Label
        SaveDirectoryLamp              matlab.ui.control.Lamp
        StitchingAcquisitionLampLabel  matlab.ui.control.Label
        StitchingAcquisitionLamp       matlab.ui.control.Lamp
        ZFocusLampLabel                matlab.ui.control.Label
        ZFocusLamp                     matlab.ui.control.Lamp
        GridStatusLampLabel            matlab.ui.control.Label
        GridStatusLamp                 matlab.ui.control.Lamp
        AcquireImagesButton            matlab.ui.control.Button
        filename_ext                   matlab.ui.control.Label
        SystemStatusPanel              matlab.ui.container.Panel
        Camera_status_label            matlab.ui.control.Label
        MagnificationLabel_2           matlab.ui.control.Label
        MicroscopyMethodLabel          matlab.ui.control.Label
        GridSizeLabel_2                matlab.ui.control.Label
        Magnification_status           matlab.ui.control.Label
        Microscopy_status              matlab.ui.control.Label
        Grid_size_status               matlab.ui.control.Label
        Camera_status                  matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        System_status                  matlab.ui.control.Label
        CurrentOperationLabel          matlab.ui.control.Label
        Camera_view                    matlab.ui.control.Button
        XYstageControlPanel            matlab.ui.container.Panel
        x_coord_entry                  matlab.ui.control.EditField
        y_coord_entry                  matlab.ui.control.EditField
        text54                         matlab.ui.control.Label
        text54_2                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_3                       matlab.ui.control.Label
        text54_4                       matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        y_coord_text                   matlab.ui.control.Label
        FocusUpButton                  matlab.ui.control.Button
        FocusPosLabel                  matlab.ui.control.Label
        FocusPos                       matlab.ui.control.Label
        FocusDownButton                matlab.ui.control.Button
        FocusPos_2                     matlab.ui.control.Label
        StepSizeEditFieldLabel         matlab.ui.control.Label
        Step_size                      matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        SnapShotPreviewButton          matlab.ui.control.Button
        ImagePreviewPanel              matlab.ui.container.Panel
        Image_Preview                  matlab.ui.control.Image
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        SettingsTab                    matlab.ui.container.Tab
        ImageSettingsPanel             matlab.ui.container.Panel
        MagnificationLabel             matlab.ui.control.Label
        MagnificationDropDown          matlab.ui.control.DropDown
        ExposureTime1Label             matlab.ui.control.Label
        ExposureTime1EditField         matlab.ui.control.NumericEditField
        msLabel_2                      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        ExposureTime2EditFieldLabel    matlab.ui.control.Label
        ExposureTime2EditField         matlab.ui.control.NumericEditField
        AcquisitionPanel               matlab.ui.container.Panel
        MicroscopeSetupLabel           matlab.ui.control.Label
        MicroscopeSetupDropDown        matlab.ui.control.DropDown
        AcqusitionTypeLabel            matlab.ui.control.Label
        AcqusitionTypeDropDown_2       matlab.ui.control.DropDown
        SnapshotExposureTimeLabel      matlab.ui.control.Label
        Exposure_time                  matlab.ui.control.NumericEditField
        LinearPolarizersPanel          matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        CurrentposLabel                matlab.ui.control.Label
        Rotate_Detection               matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DetectionEditFieldLabel        matlab.ui.control.Label
        DetectionEditField             matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel     matlab.ui.control.Label
        IlluminationEditField          matlab.ui.control.NumericEditField
        Rotate_Detection_2             matlab.ui.control.Button
        BrowseButton_2                 matlab.ui.control.Button
        SaveDirectoryLabel             matlab.ui.control.Label
        SaveDirectory                  matlab.ui.control.EditField
        GridLayoutPanel                matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        CornersTab                     matlab.ui.container.Tab
        calculate_grid_button          matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        set_UL_coords_button           matlab.ui.control.Button
        set_LR_coords_button           matlab.ui.control.Button
        SingleCornerTab                matlab.ui.container.Tab
        set_grid_button                matlab.ui.control.Button
        Image_3                        matlab.ui.control.Image
        set_up_left_button             matlab.ui.control.Button
        set_low_right_button           matlab.ui.control.Button
        LoadGridButton                 matlab.ui.control.Button
        GridSizeLabel                  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        StartPositionLabel             matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        mmLabel                        matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        AutoFocusCheckBox              matlab.ui.control.CheckBox
        SetZFocusButton                matlab.ui.control.Button
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        StitchingTypeDropDownLabel     matlab.ui.control.Label
        StitchingTypeDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        UL_x;
        UL_y;
        x_coord_motor=55;
        y_coord_motor=37.5;
        det_degree_settings;
        ill_degree_settings;
        det_RCA_LCA_settings;
        ill_RCA_LCA_settings;
        motor_x;
        motor_y;
        motor_ill;
        motor_det;
        save_folder;
        z_step = 1; % 1 micron
        saved_coordinates = cell(1,1);
        saved_coordinates_string = cell(1,1);
        saved_z_coordinates = zeros(1,3);
        saved_z_coordinates_string = cell(1,1);
        
        zstack_display = zeros(1200,1600,41);
        zstack_fm = zeros(41,1);
        i_zstack = 0;
        
        % FOV in microns calculated from calibration slide for each magnification
        mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
        mags = [4 10 16 20 32 40 64 60 96]';
        pixels_per_100_microns = [54 136 210 270 420 542 840 815 1310]';
        FOV_pixels = [1200; 1600]; % [y, x]
    
        mag_index = 8;
        obj_string = '';
        
        overlap_for_stitching = 0.25; % percent
        
        stitching_perimeter = zeros(1,2);
        stitching_coordinates = 0;
        rectangular_stitching = 0;
        perimeter_stitching = 0;
        rectangular_stitch_folder = '';
        perimeter_stitch_folder = '';
        ULx = 0;
        ULy = 0;
        LRx = 0;
        LRy = 0;
        grid_size = 0;
        grid_size_x = 2;
        grid_size_y = 2;
        x_grid_mesh;
        y_grid_mesh;
        x_img_mesh;
        y_img_mesh;
        empty_tile = false;
        z_img_mesh = 0;
        FOV_microns_y;
        FOV_microns_x;
        FOV_microns_stitching_y;
        FOV_microns_stitching_x;
        mmc;
        saved_z_positions;
        z_position;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%% add ons for new GUI %%%%%%%%%%%%%%%%%%%
        path_location = '\\ad\eng\users\a\l\algray\My Documents\MATLAB\qBRM\'; % main folder location
        Experimental_file_location = ''; % create experiment folder
        imaging_setup = {'Cross Circular Polarizer LCP-RCA', 'Cross Circular Polarizer RCP-LCA', 'Brightfield RCP-RCA', 'Brightfield LCP-LCA','Rotating Linear Polarizer'};
        
    end
    
    methods (Access = private)
       function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);      
        end
        
        function move_motor(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end
                
        function moveto(app, motor, position)
            try
            workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
            motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
            motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed')
                motor.Home(100000);
            end
        end
        function ask_to_insert_16x_lens(app)
            % Ask user to insert 1.6x lens into detection path
            d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
        end
        function ask_user_mmc(app, display_text)
            if(strcmp(app.camera_status.Text, 'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    display_text);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
        end
        

        function fig1 = plot_grid(app, x_min, x_max, y_min, y_max)
            % Plotting
            fig1 = figure('Position',[300 50 1200 900], 'Parent', app.UIAxes);
            hold on;
            plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
            hold on;
            plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
            plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
            xlim([x_min-1.5 x_max+1.5]);
            ylim([y_min-1.5 y_max+1.5]);
            ax = gca;
            ax.YDir = 'reverse';
            ax.XDir = 'reverse';
            xlabel('X coordinates (mm)');
            ylabel('Y coordinates (mm)');
        end
        
        function load_camera_with_mmc(app)
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
        
            app.Camera_status.Text = 'MATLAB';
            
        end
        
        function input_save_filename(app)
            title = 'Enter the save name for this image set.';
            user_answer = inputdlg('Enter the save name for this image set:',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            cd(app.save_folder);
            mkdir(save_string);
            app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.rectangular_stitch_folder);
        end
        
        % Save multi-exposure images for current xy location
        function save_multi_exp_images(app, exp_times, img_index, img, z_focus)
            for i = 1:length(exp_times)
                cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);

                if(img_index < 10)
                    new_filename = sprintf('img_00%d.tif',img_index);
                elseif(img_index > 99)
                    new_filename = sprintf('img_%d.tif',img_index);
                else
                    new_filename = sprintf('img_0%d.tif',img_index);
                end
                % Save images with indexing for ImageJ Stitching plug-in
                imwrite(uint16(img{y,x}(:,:,i)),new_filename);
    
                % Update saved Z-plane information
                save('z_focus.mat','z_focus');
            end
        end
        
        %disconnects all motors
        function disconnect_all_motors(app)
            app.motor_x.StopPolling();
            app.motor_x.Disconnect();
            
            app.motor_y.StopPolling();
            app.motor_y.Disconnect();
            
            app.motor_ill.StopPolling();
            app.motor_ill.Disconnect();
            
            app.motor_det.StopPolling();
            app.motor_det.Disconnect();
        end
        
        %opens window to choose magnification
        function [mag, mag_index] = choose_magnification(app)
            [app.mag_index,~] = listdlg('PromptString','Choose the magnification for stitching acquisition:','SelectionMode','Single',...
                'Name','Magnification Selection','ListSize',[300 200],'ListString',app.mag_list);
            mag = app.mags(app.mag_index,1);
            if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                app.obj_string = app.mag_list{1,app.mag_index};
            else
                app.obj_string = app.mag_list{1,app.mag_index - 1};
            
                % Ask user to insert 1.6x lens into detection path
                d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            mag_index = app.mag_index;
        end   
        
        %find z position, so the sample is in focus
        function [img, current_z] = autofocusing(app, correction_factor, exp_time, objective, z_focus_last)
                % Variable correction_factor is used to correct for the slight displacement
                % in z between images collected with the 10X and 40X objectives
                app.mmc.setConfig('Objective', objective);
                current_z = app.mmc.getPosition;
            
                done = 0;
                fine_adj = 0;
                n = 0;
            
                disp('Finding best focus...');
                while(done == 0)
                    n = n + 1;
                    fprintf('Iteration #%d  ',n);
                    if(fine_adj == 0)
                        increment = 50;
                        fprintf('(coarse) \n');
                    else
                        increment = 5;
                        fprintf('(fine) \n');
                    end
            
                    if(n == 1)
                        if(strcmp(z_focus_last,'current position') == 1)
                            current_z = app.mmc.getPosition;
                            pause(1);
                        elseif (strcmp(z_focus_last,'nothing') == 1)
                            %nothing
                        else
                            current_z = z_focus_last;
                        end
                    elseif(n == 2)
                        direction = 1; % positive
                        current_z = current_z + direction*increment; % Move objective block up
                    else
                        % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                        if(fm(n - 1,1) > fm(n - 2,1))
                            % Don't change direction
                        else
                            direction = -direction;
                        end
                        current_z = current_z + direction*increment;
                    end
            
            
                    app.mmc.setPosition(current_z);
                    pause(1);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
            
                    % After n = 3 iteration, the direction the z-stage needs to move has been determined
                    if(n > 3)
                        if(fm(n,1) < fm(n-1,1))
                            current_z = current_z - direction*increment;
                            if(fine_adj == 0)
                                fine_adj = 1;
                                n = 0;
                            else
                                done = 1;
                            end
                        end
                    end
            
                end
  
                current_z = round(app.mmc.getPosition - correction_factor); % Round to nearest integer
        end
        
        function Disable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'off';
            app.SnapShotPreviewButton.Enable = 'off';
            app.y_coord_entry.Enable = 'off';
            app.x_coord_entry.Enable = 'off';
            app.MoveXYmotorButton.Enable = 'off';
            app.FileName.Enable = 'off';
            app.FocusUpButton.Enable = 'off';
            app.FocusDownButton.Enable = 'off';
            app.Camera_view.Enable = 'off'
            app.Step_size.Enable = 'off'
            
        end
        
        function Enable_btn_functionality(app)
            app.AcquireImagesButton.Enable = 'on';
            app.SnapShotPreviewButton.Enable = 'on';
            app.y_coord_entry.Enable = 'on';
            app.x_coord_entry.Enable = 'on';
            app.MoveXYmotorButton.Enable = 'on';
            app.FileName.Enable = 'on';
            app.FocusUpButton.Enable = 'on';
            app.FocusDownButton.Enable = 'on';
            app.Camera_view.Enable = 'on'
            app.Step_size.Enable = 'on'
        end
        
        function acquire_preview_image(app)
            img = snap_image(app.Exposure_time.Value,app.mmc);
            app.System_status.Text = 'Acquiring Image';
            Disable_btn_functionality(app)
            app.Image_Preview.ImageSource = repmat(img, 1, 1, 3);
            app.System_status.Text = 'Standby';
            Enable_btn_functionality(app)
        end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to control_new_offline_copy (see VARARGIN)
            
            Disable_btn_functionality(app)
            app.System_status.Text = 'Iniatializing...';
            
            % Choose default command line output for control_new_offline_copy
            current_folder = pwd;
            addpath(current_folder);
            [~, bigio_index_end] = regexp(current_folder,'_Bigio');
            bigio_folder = current_folder(1:bigio_index_end);
            
            % Include path to MATLAB folder
            matlab_folder = [bigio_folder,'\MATLAB'];
            addpath(matlab_folder);
            
            % Include path to functions
            addpath([matlab_folder,'\functions']);
            % Load motorized rotational stages and settings
            for nnn = 1:1
                calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
                addpath(calibration_path{1,1});
                ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
                det_degree_settings = load('det_5_degree_settings.mat'); %detection
                calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
                addpath(calibration_path{2,1});
                ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
                det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
                addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
                % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local
            
                field_names = fieldnames(det_degree_settings);
                app.det_degree_settings = det_degree_settings.(field_names{1,1});
                field_names = fieldnames(ill_degree_settings);
                app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
                field_names = fieldnames(det_RCA_LCA_settings);
                app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
                field_names = fieldnames(ill_RCA_LCA_settings);
                app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
                clearvars field_names
            
                SN_ill = '55001048';
                SN_det = '55001045';
                SN_xy_controller = '73109054';
            
                %create motor objects and home devices
                [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
                app.motor_ill = rotat_stage_init(SN_ill);
                app.motor_det = rotat_stage_init(SN_det);

            end
            
            % Start in LCP-RCA configuration
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);

            
            %app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
            %app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1)); 
            
            % Create time-stamped save folder each time an imaging session is started
            t = clock;
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
                t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            cd([bigio_folder,'\Images']);
            mkdir(new_folder_name);
            app.save_folder = [bigio_folder,'\Images\',new_folder_name];
            %app.save_dir_user_entry.Value = app.save_folder;
            cd(app.save_folder);
            
            max_slider_val = 36;
            min_slider_val = 1;
            slider_steps1 = 1/(max_slider_val - min_slider_val);
            slider_steps2 = 3/(max_slider_val - min_slider_val);
            %app.LP_angle_slider.Limits = [min_slider_val max_slider_val];
            
            %app.LP_angle_slider.MajorTicks = min_slider_val:slider_steps1:max_slider_val;

            %
            load_camera_with_mmc(app);
            %!C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            
            app.FOV_microns_y = 100*app.FOV_pixels(1,1)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.FOV_pixels(2,1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
            
            % load in the current focus position
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            
            %%%%%%%%%%% after Old GUI %%%%%%%%%%%%%%%%%%%%%
            current_folder = pwd;
            current_folder = strcat(current_folder, "\");
            %addpath(current_folder);
            %[~, bigio_index_end] = regexp(current_folder,'_Bigio');
            %bigio_folder = current_folder(1:bigio_index_end);
            %cd([bigio_folder,'\Images']);
            
            t = clock;
            
            % can replace with an experimental file
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            new_folder_name = strcat(current_folder, new_folder_name);
            app.SaveDirectory.Value = new_folder_name;
            
            app.FileName.Value = new_folder_name;
            
            % load grid into window axis
            %fig1 = plot_grid(app, x_min, x_max, y_min, y_max)
          
            
            %%%% set all default values
            app.MagnificationDropDown.Value = app.mag_list{1,app.mag_index};
            app.Microscopy_status.Text = app.MicroscopeSetupDropDown.Value;
            app.Magnification_status.Text = app.MagnificationDropDown.Value;
            grid_1 = strcat(string(app.grid_size_x),'x');
            grid_string = strcat(grid_1,string(app.grid_size_y));
            app.Grid_size_status.Text = grid_string;
            Enable_btn_functionality(app)
            app.System_status.Text = 'Standby';
            disp('Initialization finished');
        end

        % Size changed function: SingleCornerTab
        function BrowseButtonPushed(app, event)
             user_selection = uigetdir();
             
             disp(user_selection);
             
             app.Label_5.Text = strcat('File Location: ', user_selection); % update label for experiment location 
             
             
        end

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            value = app.MagnificationDropDown.Value;
            app.Magnification_status.Text = value;
            disp(value)
            app.mmc.setConfig('Objective', app.MagnificationDropDown.Value); % set magnification
            
            
        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            move_motor(app,app.x_coord_motor, app.y_coord_motor);
        end

        % Button pushed function: SnapShotPreviewButton
        function SnapShotPreviewButtonPushed(app, event)
            acquire_preview_image(app)
           
        end

        % Button pushed function: Camera_view
        function Camera_viewPushed(app, event)
            if strcmp(app.Camera_status.Text, 'MATLAB')
                app.mmc.reset();
                app.FocusDownButton.Enable = 'off';
                app.FocusUpButton.Enable = 'off';
                % Open Micro-Manager Launcher
                app.System_status.Text = 'Opening Micro-Manager'
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                app.Camera_status.Text = 'Micro-Manager'
                app.Camera_view.text = 'Open MATLAB'
            else strcmp(app.Camera_status.Text, 'Micro-Manager')
                load_camera_with_mmc(app)
                app.FocusDownButton.Enable = 'on';
                app.FocusUpButton.Enable = 'on';
            end
        end

        % Button pushed function: FocusUpButton
        function FocusUpButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position + app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            % acquire Image and update
             acquire_preview_image(app)
        end

        % Button pushed function: FocusDownButton
        function FocusDownButtonPushed(app, event)
            % move z focus
            app.z_position = app.z_position - app.Step_size.Value; % increment by Z Focus Step size
            app.mmc.setPosition(app.z_position);
            % set position string
            formatSpec = '%.1f';
            app.z_position = app.mmc.getPosition;
            app.FocusPos.Text = num2str(app.z_position, formatSpec);
            
            %acquire image for preview
             acquire_preview_image(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.AcquisitionStatusPanel = uipanel(app.AcquisitionTab);
            app.AcquisitionStatusPanel.Title = 'Acquisition Status';
            app.SaveDirectoryLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.SaveDirectoryLampLabel.Text = 'Save Directory';
            app.SaveDirectoryLamp = uilamp(app.AcquisitionStatusPanel);
            app.StitchingAcquisitionLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.StitchingAcquisitionLampLabel.Text = 'Stitching Acquisition ';
            app.StitchingAcquisitionLamp = uilamp(app.AcquisitionStatusPanel);
            app.ZFocusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.ZFocusLampLabel.Text = 'Z Focus';
            app.ZFocusLamp = uilamp(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel.Text = 'Grid Status';
            app.GridStatusLamp = uilamp(app.AcquisitionStatusPanel);
            app.AcquireImagesButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.filename_ext = uilabel(app.AcquisitionTab);
            app.filename_ext.Text = '_exposure-time_magnification';
            app.SystemStatusPanel = uipanel(app.AcquisitionTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.Camera_status_label = uilabel(app.SystemStatusPanel);
            app.Camera_status_label.Text = 'Camera Status: ';
            app.MagnificationLabel_2 = uilabel(app.SystemStatusPanel);
            app.MagnificationLabel_2.Text = 'Magnification:';
            app.MicroscopyMethodLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopyMethodLabel.Text = 'Microscopy Method: ';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Magnification_status = uilabel(app.SystemStatusPanel);
            app.Magnification_status.Text = '60x';
            app.Microscopy_status = uilabel(app.SystemStatusPanel);
            app.Microscopy_status.Text = 'Crossed Circular Polarizer';
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status.Text = '2x2';
            app.Camera_status = uilabel(app.SystemStatusPanel);
            app.Camera_status.Text = 'Micromanager';
            app.Label_5 = uilabel(app.SystemStatusPanel);
            app.Label_5.Text = '--------------------------------------------------------------------------------------';
            app.System_status = uilabel(app.SystemStatusPanel);
            app.System_status.Text = 'Standby';
            app.CurrentOperationLabel = uilabel(app.SystemStatusPanel);
            app.CurrentOperationLabel.Text = 'Current Operation:';
            app.Camera_view = uibutton(app.SystemStatusPanel, 'push');
            app.Camera_view.ButtonPushedFcn = createCallbackFcn(app, @Camera_viewPushed, true);
            app.Camera_view.Text = 'Change Camera View';
            app.XYstageControlPanel = uipanel(app.AcquisitionTab);
            app.XYstageControlPanel.Title = 'XY-stage Control';
            app.x_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.XYstageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.XYstageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.MoveXYmotorButton = uibutton(app.XYstageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_3 = uilabel(app.XYstageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.XYstageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.XYstageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.XYstageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.FocusUpButton = uibutton(app.XYstageControlPanel, 'push');
            app.FocusUpButton.ButtonPushedFcn = createCallbackFcn(app, @FocusUpButtonPushed, true);
            app.FocusUpButton.Text = 'Focus Up';
            app.FocusPosLabel = uilabel(app.XYstageControlPanel);
            app.FocusPosLabel.Text = 'Focus-Pos';
            app.FocusPos = uilabel(app.XYstageControlPanel);
            app.FocusPos.Text = 'NaN';
            app.FocusDownButton = uibutton(app.XYstageControlPanel, 'push');
            app.FocusDownButton.ButtonPushedFcn = createCallbackFcn(app, @FocusDownButtonPushed, true);
            app.FocusDownButton.Text = 'Focus Down';
            app.FocusPos_2 = uilabel(app.XYstageControlPanel);
            app.FocusPos_2.Text = 'NA';
            app.StepSizeEditFieldLabel = uilabel(app.XYstageControlPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.Step_size = uieditfield(app.XYstageControlPanel, 'numeric');
            app.Step_size.Value = 1;
            app.mLabel = uilabel(app.XYstageControlPanel);
            app.mLabel.Text = 'µm';
            app.SnapShotPreviewButton = uibutton(app.AcquisitionTab, 'push');
            app.SnapShotPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotPreviewButtonPushed, true);
            app.SnapShotPreviewButton.Text = 'SnapShot Preview';
            app.ImagePreviewPanel = uipanel(app.AcquisitionTab);
            app.ImagePreviewPanel.Title = 'Image Preview';
            app.Image_Preview = uiimage(app.ImagePreviewPanel);
            app.FileNameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileName = uieditfield(app.AcquisitionTab, 'text');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ImageSettingsPanel = uipanel(app.SettingsTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.MagnificationLabel = uilabel(app.ImageSettingsPanel);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.ImageSettingsPanel);
            app.MagnificationDropDown.Items = {'4X', '10X', '16X', '20X', '32X', '40X', '60X', '64X', '96X'};
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = '4X';
            app.ExposureTime1Label = uilabel(app.ImageSettingsPanel);
            app.ExposureTime1Label.Text = 'Exposure Time (1):';
            app.ExposureTime1EditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime1EditField.Value = 50;
            app.msLabel_2 = uilabel(app.ImageSettingsPanel);
            app.msLabel_2.Text = 'ms';
            app.msLabel = uilabel(app.ImageSettingsPanel);
            app.msLabel.Text = 'ms';
            app.ExposureTime2EditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2):';
            app.ExposureTime2EditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime2EditField.Value = 100;
            app.AcquisitionPanel = uipanel(app.SettingsTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.MicroscopeSetupLabel = uilabel(app.AcquisitionPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.MicroscopeSetupDropDown = uidropdown(app.AcquisitionPanel);
            app.MicroscopeSetupDropDown.Items = {'Crossed Circular Polarizer', 'qBRM', 'Brightfield Microscopy', 'Rotating Linear Polarizer'};
            app.MicroscopeSetupDropDown.Value = 'Crossed Circular Polarizer';
            app.AcqusitionTypeLabel = uilabel(app.AcquisitionPanel);
            app.AcqusitionTypeLabel.Text = 'Acqusition Type:';
            app.AcqusitionTypeDropDown_2 = uidropdown(app.AcquisitionPanel);
            app.AcqusitionTypeDropDown_2.Items = {'Single Image', 'Stitching', 'Widefield (4x)'};
            app.AcqusitionTypeDropDown_2.Value = 'Single Image';
            app.SnapshotExposureTimeLabel = uilabel(app.AcquisitionPanel);
            app.SnapshotExposureTimeLabel.Text = 'Snapshot Exposure Time:';
            app.Exposure_time = uieditfield(app.AcquisitionPanel, 'numeric');
            app.Exposure_time.Limits = [0 5000];
            app.Exposure_time.Value = 400;
            app.LinearPolarizersPanel = uipanel(app.SettingsTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.Label_3 = uilabel(app.LinearPolarizersPanel);
            app.Label_3.Text = '14.8 °';
            app.Label_4 = uilabel(app.LinearPolarizersPanel);
            app.Label_4.Text = '120.03 ° ';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Rotate_Detection = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection.Text = 'Rotate';
            app.Label_2 = uilabel(app.LinearPolarizersPanel);
            app.Label_2.Text = '°';
            app.Label = uilabel(app.LinearPolarizersPanel);
            app.Label.Text = '°';
            app.DetectionEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionEditFieldLabel.Text = 'Detection';
            app.DetectionEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.DetectionEditField.Limits = [0 180];
            app.IlluminationEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationEditField.Limits = [0 180];
            app.Rotate_Detection_2 = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection_2.Text = 'Rotate';
            app.BrowseButton_2 = uibutton(app.SettingsTab, 'push');
            app.BrowseButton_2.Text = 'Browse...';
            app.SaveDirectoryLabel = uilabel(app.SettingsTab);
            app.SaveDirectoryLabel.Text = 'Save Directory:';
            app.SaveDirectory = uieditfield(app.SettingsTab, 'text');
            app.GridLayoutPanel = uipanel(app.SettingsTab);
            app.GridLayoutPanel.Title = 'Grid Layout';
            app.UIAxes = uiaxes(app.GridLayoutPanel);
            title(app.UIAxes, 'Grid Selection')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup2 = uitabgroup(app.GridLayoutPanel);
            app.CornersTab = uitab(app.TabGroup2);
            app.CornersTab.Title = '2 Corners';
            app.calculate_grid_button = uibutton(app.CornersTab, 'push');
            app.calculate_grid_button.Tag = 'calculate_grid_button';
            app.calculate_grid_button.Text = 'Calculate Grid Size';
            app.Image_2 = uiimage(app.CornersTab);
            app.Image_2.ImageSource = 'box.png';
            app.set_UL_coords_button = uibutton(app.CornersTab, 'push');
            app.set_UL_coords_button.Tag = 'set_UL_coords_button';
            app.set_UL_coords_button.Text = 'Set UL';
            app.set_LR_coords_button = uibutton(app.CornersTab, 'push');
            app.set_LR_coords_button.Tag = 'set_LR_coords_button';
            app.set_LR_coords_button.Text = 'Set LR';
            app.SingleCornerTab = uitab(app.TabGroup2);
            app.SingleCornerTab.SizeChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.SingleCornerTab.Title = 'Single Corner';
            app.set_grid_button = uibutton(app.SingleCornerTab, 'push');
            app.set_grid_button.Tag = 'set_grid_button';
            app.set_grid_button.Text = 'Set Grid Size';
            app.Image_3 = uiimage(app.SingleCornerTab);
            app.Image_3.ImageSource = 'box.png';
            app.set_up_left_button = uibutton(app.SingleCornerTab, 'push');
            app.set_up_left_button.Tag = 'set_up_left_button';
            app.set_up_left_button.Text = 'Set UL';
            app.set_low_right_button = uibutton(app.SingleCornerTab, 'push');
            app.set_low_right_button.Tag = 'set_low_right_button';
            app.set_low_right_button.Text = 'Set LR';
            app.LoadGridButton = uibutton(app.GridLayoutPanel, 'push');
            app.LoadGridButton.Text = 'Load Grid ';
            app.GridSizeLabel = uilabel(app.GridLayoutPanel);
            app.GridSizeLabel.Text = 'Grid Size: ';
            app.EditField = uieditfield(app.GridLayoutPanel, 'numeric');
            app.EditField.Value = 2;
            app.xEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayoutPanel, 'numeric');
            app.xEditField.Value = 2;
            app.StartPositionLabel = uilabel(app.GridLayoutPanel);
            app.StartPositionLabel.Text = 'Start Position:';
            app.Switch = uiswitch(app.GridLayoutPanel, 'slider');
            app.Switch.Items = {'Upper Left', 'Lower Right'};
            app.Switch.Value = 'Upper Left';
            app.mmLabel = uilabel(app.GridLayoutPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.GridLayoutPanel);
            app.mmLabel_2.Text = 'mm';
            app.AutoFocusCheckBox = uicheckbox(app.GridLayoutPanel);
            app.AutoFocusCheckBox.Text = 'Auto Focus';
            app.SetZFocusButton = uibutton(app.GridLayoutPanel, 'push');
            app.SetZFocusButton.Text = 'Set Z-Focus';
            app.XEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.GridLayoutPanel, 'numeric');
            app.XEditField.Value = 12.456;
            app.YEditField = uieditfield(app.GridLayoutPanel, 'numeric');
            app.YEditField.Value = 12.5;
            app.YEditFieldLabel = uilabel(app.GridLayoutPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.StitchingTypeDropDownLabel = uilabel(app.GridLayoutPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingTypeDropDown = uidropdown(app.GridLayoutPanel);
            app.StitchingTypeDropDown.Items = {'Single Plane', 'Multi Plane'};
            app.StitchingTypeDropDown.Value = 'Single Plane';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRM_Microscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end